classdef Quadcopter_App < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure  matlab.ui.Figure
      Button2   matlab.ui.control.Button
      UIAxes    matlab.ui.control.UIAxes
   end


   properties
      quad;
   end
   methods (Access = public)

      function result = func(app)
         app.Altimeter.Altitude = 200;

      end
   end


   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)

         %          app.Altimeter.Value = 50;
         %          app.Altimeter.Altitude = 100;
         
         inc = 0.01;
         timeEnd = 30;
         t = 0:inc:timeEnd;
         addpath(genpath('./Addition'));
         quadParams = ReadProperty("CloverProp.pdf");
         initState = [0; 0; zeros(10,1)];
         initInput = zeros(4,1);


         app.quad = Quadcopter(quadParams, initState, initInput, t);
%          plot(app.UIAxes)
         
         
         %          plot(app.UIAxes,t)
         %          axis([-1 1 -1 1 -0.2 3])
         %          camlight
      end

      % Button pushed function: Button2
      function Button2Pushed(app, event)
         
         inc = 0.01;
         timeEnd = 30;
         t = 0:inc:timeEnd;
         figure(app.UIFigure)
         app.quad.Model3D('CloverAssemblyP.PLY');
%          plot(app.UIAxes,app.quad.model)
%          plot(app.UIAxes,app.quad.simSize)
%          plot(app.UIAxes,app.quad.model)
         plot3(app.UIAxes,[0 5],[9 3],[1 3])
         app.quad.MoveObj([0; 1; 2], [0; 0; 0])
         plot(app.UIAxes,app.quad.objMesh_h)
         camlight
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'MATLAB App';
         app.UIAxes = uiaxes(app.UIFigure);
         title(app.UIAxes, 'Title')
         xlabel(app.UIAxes, 'X')
         ylabel(app.UIAxes, 'Y')
         zlabel(app.UIAxes, 'Z')
         app.Button2 = uibutton(app.UIFigure, 'push');
         app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
         app.Button2.Text = 'Button2';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = Quadcopter_App

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @startupFcn)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end